classdef bp_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure                % UI Figure
        ButtonGroup         matlab.ui.container.ButtonGroup % Gender
        MaleRadioButton     matlab.ui.control.RadioButton   % Male
        FemaleRadioButton   matlab.ui.control.RadioButton   % Female
        LabelSwitch         matlab.ui.control.Label         % Switch
        BloodPressureSwitch matlab.ui.control.Switch        % Systolic, Dia...
        PlotButton          matlab.ui.control.Button        % Plot
        UIAxes              matlab.ui.control.UIAxes        % Weight vs Blo...
        Label               matlab.ui.control.Label         % Blood Pressur...
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run('patient_data_generator')
        end

        % PlotButton button pushed function
        function CallButtonPushed(app)
            % load the data
            load BP_Data.mat
            
            % Check gender and blood pressure
            bp = app.BloodPressureSwitch.Value;
            
            if strcmp(bp, 'Systolic')
                if app.FemaleRadioButton.Value == true
                    x = weight_f;
                    y = sys_f;
                else 
                    x = weight_m;
                    y = sys_f;
                end 
            else
                if app.FemaleRadioButton.Value == true
                    x = weight_f;
                    y = dias_f;
                else 
                    x = weight_m;
                    y = dias_m;
                end
            end
            
            % Then create a scatter plot
            scatter(app.UIAxes, x, y)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Gender';
            app.ButtonGroup.Units = 'pixels';
            app.MaleRadioButton = uiradiobutton(app.ButtonGroup);
            app.MaleRadioButton.Text = 'Male';
            app.MaleRadioButton.Value = true;
            app.FemaleRadioButton = uiradiobutton(app.ButtonGroup);
            app.FemaleRadioButton.Text = 'Female';
            app.LabelSwitch = uilabel(app.UIFigure);
            app.LabelSwitch.Text = 'Switch';
            app.BloodPressureSwitch = uiswitch(app.UIFigure, 'slider');
            app.BloodPressureSwitch.Items = {'Systolic', 'Diastolic'};
            app.BloodPressureSwitch.Value = 'Systolic';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @CallButtonPushed);
            app.PlotButton.Text = 'Plot';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Weight vs Blood Pressure');
            xlabel(app.UIAxes, 'Weight');
            ylabel(app.UIAxes, 'Blood Pressure');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Blood Pressure Measurement App';
        end
    end

    methods (Access = public)

        % Construct app
        function app = bp_app()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end